classdef ProjectExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ProjectMenu         matlab.ui.container.Menu
        NewProjectMenu      matlab.ui.container.Menu
        OptionsMenu         matlab.ui.container.Menu
        EditMenu            matlab.ui.container.Menu
        HideprojectMenu     matlab.ui.container.Menu
        ShowprojectsMenu    matlab.ui.container.Menu
        MesurementsMenu     matlab.ui.container.Menu
        NewmeasuremnetMenu  matlab.ui.container.Menu
        GridLayout          matlab.ui.container.GridLayout
        PTree               matlab.ui.container.Tree
        StatusLine          matlab.ui.control.Label
        Panel               matlab.ui.container.Panel
        UITable             matlab.ui.control.Table
    end

    
    properties (Access = public)
        AEH AEClassifier; % AEClassifier handle object
        NodeCat struct; %strukltura kategorií jednotlivých stromů        
        PCategory; %Skupina kategorií projektů
        MCategory; %Skupina kategorií měření
        SCategory; %Skupina kategorií zkušebních těles
        MasterFolder char;
        US; %object of users
        PR; %projects obj
        UserID; %current user id
        CM;
        PNodeSelected;
    end
    
    properties (Access = private)
        SOPT; % Object for UIwindow of set options
    end
    
    methods (Access = private)
        
        %will load user options and save current user setings
        function LoadUserOptions(app)
            app.US=Users(app);
            app.US=CheckUser(app.US);
            SaveUserOptions(app.US);
        end
    end
    
    methods (Access = public)
        
        %will create new projec
        
        %will load all projects and its measuremnts, also can be used for
        %updating the list of projects on demand (creation, deletion)
        function LoadProjects(app)
            app.PR=ProjectOverView(app.US.UserOptions(app.UserID).SandBoxFolder,app.PTree);
            FillTree(app.PR);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name='Project explorer 1.0';
            app.StatusLine.Text='-ready for work-';
            app.MasterFolder=strrep(which('ProjectExplorer'),'ProjectExplorer.mlapp','');
            LoadUserOptions(app);
            LoadProjects(app);
        end

        % Menu selected function: NewProjectMenu
        function NewProjectMenuSelected(app, event)
            app.UIFigure.Visible='off';
            NewProject=EditProject(app);
            
        end

        % Menu selected function: NewmeasuremnetMenu
        function NewMeasurementMenuSelected(app, event)
            CreateMeas(app.PR.Projects(app.PNodeSelected),app.US.UserOptions(app.UserID).SandBoxFolder,app.PR.TReeNodes{app.PNodeSelected});
        end

        % Menu selected function: OptionsMenu
        function OptionsMenuSelected(app, event)
            app.UIFigure.Visible='off';
            app.SOPT=SetOptions(app);
        end

        % Selection changed function: PTree
        function ProjectSelectLMB(app, event)
            app.PNodeSelected = app.PTree.SelectedNodes.NodeData;
            if ~isempty(app.PNodeSelected(2))
                ClassName=class(app.PNodeSelected{2});  
                    switch ClassName
                        case 'ProjectObj'
                            %clicked on project
                        otherwise 'AE'
                            
                            %clicked on something bellow it
                    end
            end
        end

        % Menu selected function: HideprojectMenu
        function HideprojectMenuSelected(app, event)
            if app.PNodeSelected>0
                while numel(app.PTree.SelectedNodes)>0
                    n=app.PTree.SelectedNodes(1).NodeData;
                    HideProject(app.PR,n);
                    app.StatusLine.Text=['-Info: ' app.PR.Projects(n).Name ' successfully hidden!'];
                end
            else
                app.StatusLine.Text='-Error: No project was selected to hide it!';
            end
        end

        % Menu selected function: ShowprojectsMenu
        function ShowprojectsMenuSelected(app, event)
            ShowProjects(app.PR);
            app.StatusLine.Text='-Info: All project are present in explorer';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ProjectMenu = uimenu(app.UIFigure);
            app.ProjectMenu.Text = 'Project';
            app.NewProjectMenu = uimenu(app.ProjectMenu);
            app.NewProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @NewProjectMenuSelected, true);
            app.NewProjectMenu.Text = 'New Project';
            app.OptionsMenu = uimenu(app.ProjectMenu);
            app.OptionsMenu.MenuSelectedFcn = createCallbackFcn(app, @OptionsMenuSelected, true);
            app.OptionsMenu.Text = 'Options';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.HideprojectMenu = uimenu(app.EditMenu);
            app.HideprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @HideprojectMenuSelected, true);
            app.HideprojectMenu.Text = 'Hide project';
            app.ShowprojectsMenu = uimenu(app.EditMenu);
            app.ShowprojectsMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowprojectsMenuSelected, true);
            app.ShowprojectsMenu.Text = 'Show projects';
            app.MesurementsMenu = uimenu(app.UIFigure);
            app.MesurementsMenu.Text = 'Mesurements';
            app.NewmeasuremnetMenu = uimenu(app.MesurementsMenu);
            app.NewmeasuremnetMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMeasurementMenuSelected, true);
            app.NewmeasuremnetMenu.Text = 'New measuremnet';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.PTree = uitree(app.GridLayout);
            app.PTree.Multiselect = 'on';
            app.PTree.SelectionChangedFcn = createCallbackFcn(app, @ProjectSelectLMB, true);
            app.StatusLine = uilabel(app.GridLayout);
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = 'Panel';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectExplorer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end