classdef ShowData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        UITable      matlab.ui.control.Table
        CloseButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        ProjectExplorerHandle; % Description
        Open logical;
    end
    
    methods (Access = private)
        

    end
    
    methods (Access = public)
        
        function UpdateWindow(app,Tab)
            app.UITable.Data=Tab;
        end
        
        function FillTable(app,Tab)
            app.Open=true;
            app.UIFigure.Visible="on";
            
            app.UITable.Data=Tab;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, WHandle)

            app.ProjectExplorerHandle=WHandle;
            app.Open=false;
            app.UIFigure.Visible="off";
            app.UITable.ColumnName={'Parameters','Values'};
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.ProjectExplorerHandle.ShowWindowH=[];
            app.Open=false;
            app.UIFigure.Visible="off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ShowData(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end