classdef ProjectExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ProjectMenu            matlab.ui.container.Menu
        NewProjectMenu         matlab.ui.container.Menu
        DeleteprojectMenu      matlab.ui.container.Menu
        OptionsMenu            matlab.ui.container.Menu
        SaveworkMenu           matlab.ui.container.Menu
        ProjectoperationMenu   matlab.ui.container.Menu
        ExportoverviewMenu     matlab.ui.container.Menu
        ResetselectorsMenu     matlab.ui.container.Menu
        EditMenu               matlab.ui.container.Menu
        HideprojectMenu        matlab.ui.container.Menu
        ShowprojectsMenu       matlab.ui.container.Menu
        EdittypesMenu          matlab.ui.container.Menu
        MesurementsMenu        matlab.ui.container.Menu
        NewmeasuremnetMenu     matlab.ui.container.Menu
        EditmeasurementMenu    matlab.ui.container.Menu
        ReloaddataMenu         matlab.ui.container.Menu
        DeleteMenu             matlab.ui.container.Menu
        ReadDataMenu           matlab.ui.container.Menu
        GridLayout             matlab.ui.container.GridLayout
        PTree                  matlab.ui.container.Tree
        StatusLine             matlab.ui.control.Label
        Panel                  matlab.ui.container.Panel
        DropDownLabel          matlab.ui.control.Label
        DropDown               matlab.ui.control.DropDown
        UITable                matlab.ui.control.Table
        DropDownSelector       matlab.ui.control.DropDown
        NewButton              matlab.ui.control.Button
        DelButton              matlab.ui.control.Button
        ShowButton             matlab.ui.control.Button
        SystemUsage            matlab.ui.control.Label
        SetLabel               matlab.ui.control.Label
        ProjectstructureLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        AEH AEClassifier; % AEClassifier handle object
        NodeCat struct; %strukltura kategorií jednotlivých stromů        
        PCategory; %Skupina kategorií projektů
        MCategory; %Skupina kategorií měření
        SCategory; %Skupina kategorií zkušebních těles
        MasterFolder char;
        US; %object of users
        PR; %projects overview obj
        UserID; %current user id
        CM;
        PNodeSelected;
        UITabRow;
        CurrNode;
        ShowWindowH ShowData;
        DataCore;
        TypeSelWin;
        CurrSell;
        FP; %field plotter
        PlotApp;
    end
    
    properties (Access = private)
        SOPT; % Object for UIwindow of set options
    end
    
    methods (Access = private)
        
        %will load user options and save current user setings
        function LoadUserOptions(app)
            app.US=Users(app);
            app.US=CheckUser(app.US);
            SaveUserOptions(app.US);
        end
        
        function [bool] = CheckTreeNode(app,Str)
            if class(app.PNodeSelected)~="double"
                if ~isempty(app.PNodeSelected(2))
                    %ClassName=string(class(app.PNodeSelected{2}));  
                    superclass=superclasses(app.PNodeSelected{2});
                    if strcmp(superclass{1},'handle')
                        Class=class(app.PNodeSelected{2});
                        bool = strcmp(string(Class),Str);
                        return;
                    else
                        bool = strcmp(string(superclass(3)),Str);
                        return;
                    end                    
                else
                    bool=false;
                end
            else
                bool=false;
            end
        end


        function SaveWork(app)
             if CheckTreeNode(app,"ProjectObj")
                   saveobj(app.PNodeSelected{2});
             end        
        end
        
    end
    
    methods (Access = public)
        function InitiateSelectorCBX(app,nSet,ProjectID)
            if ~isempty(app.PR)
                if ~isempty(app.PR.Projects)
                    Sel=app.PR.Projects(ProjectID).SelectorSets;
                    app.PR.Projects(ProjectID).CurrentSelector=nSet;
                    if size(Sel,1)>0
                        app.DropDownSelector.Items=string({Sel.Description});
                        app.DropDownSelector.ItemsData=[Sel.Sets];
                        app.DropDownSelector.Value=nSet;
                    end
                end
            end
        end
        %will create new projec
        
        %will load all projects and its measuremnts, also can be used for
        %updating the list of projects on demand (creation, deletion)
        function LoadProjects(app)
            app.PR=ProjectOverView(app.US.UserOptions(app.UserID).SandBoxFolder,app.PTree,app);
            FillTree(app.PR);
        end
        
        function InfoUser(app,type,text)
            arguments
                app ProjectExplorer;
                type {mustBeMember(type,['status','info','warning','error'])};
                text char;
            end
            
            switch lower(type)
                case 'status'
                    app.StatusLine.BackgroundColor='k';
                    app.StatusLine.Text=['Status: ' text];
                    app.StatusLine.FontWeight='normal';
                    app.StatusLine.BackgroundColor='none';
                case 'info'
                    app.StatusLine.Text=['Info: ' text];
                    app.StatusLine.BackgroundColor=[.2 .8 .7];
                    app.StatusLine.FontWeight='bold';
                    pause(2);
                    app.StatusLine.FontWeight='normal';
                    app.StatusLine.BackgroundColor='none';
                case 'warning'
                    app.StatusLine.Text=['Warning: ' text];
                    app.StatusLine.BackgroundColor=[.93 .69 .13];
                    app.StatusLine.FontWeight='bold';
                    pause(0.5);
                    app.StatusLine.BackgroundColor='none';
                case 'error'
                    app.StatusLine.Text=['Error: ' text];
                    app.StatusLine.BackgroundColor=[1 .41 .16];
                    app.StatusLine.FontWeight='bold';
                    pause(1.2);
                    %app.StatusLine.FontWeight='normal';
                    %app.StatusLine.BackgroundColor='none';
                otherwise
            end


        end
        
        function results = GetCurrentData(app)
                results=GetData(app.FP);       
            
        end
        
        function ClearDataTypes(app)
            for i=1:numel(app.PR.Projects.Meas)
               app.PR.Projects.Meas{i}.DataTypesTable=[];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name='Project explorer 1.0';
            InfoUser(app,'status','-ready for work-');
            app.MasterFolder=strrep(which('ProjectExplorer'),'ProjectExplorer.mlapp','');
            LoadUserOptions(app);
            LoadProjects(app);
            if size(app.PR.Projects)>0
                if app.PR.Projects(1).ID>0
                    InitiateSelectorCBX(app,1,app.PR.Projects(1).ID);
                else
                    InitiateSelectorCBX(app,1,1);
                end
            else
                InitiateSelectorCBX(app,1,1);
            end
            app.ShowWindowH=ShowData(app);
            
            app.FP=FieldPlotter;
            app.PlotApp=PlotAppControl(app.Panel,app);
            
            AssociateApp(app.FP,app);
            app.DropDown.Items=['-',GetTypes(app.FP)];
        end

        % Menu selected function: NewProjectMenu
        function NewProjectMenuSelected(app, event)
            %app.UIFigure.Visible='off';
            NewProject=EditProject(app);            
        end

        % Menu selected function: NewmeasuremnetMenu
        function NewMeasurementMenuSelected(app, event)
            %nové měření může vznikat pouze u projektů, proto pokud mám
            %vybrané měření, pak potřebuji získat odkaz na projekt, pokud
            %už mám odkaz na porjekt, pak rovnou vytvořím měření
            
            if CheckTreeNode(app,"ProjectObj")
                %node=app.PNodeSelected{2}.NodeData;
                TreeNode=app.PTree.SelectedNodes;
                obj=app.PNodeSelected{2};
                SandBox=app.US.UserOptions(app.UserID).SandBoxFolder;
                CreateMeas(obj,app,SandBox,TreeNode);
            else
                %app.PNodeSelected{1, 3}.NodeData{1, 2}
                node=app.PNodeSelected{3};
                obj=node.NodeData{2};
                TreeNode=app.PNodeSelected{3};
            end


        end

        % Menu selected function: OptionsMenu
        function OptionsMenuSelected(app, event)
            app.UIFigure.Visible='off';
            app.SOPT=SetOptions(app);
        end

        % Selection changed function: PTree
        function ProjectSelectLMB(app, event)
            app.PNodeSelected = app.PTree.SelectedNodes.NodeData;
            app.CurrNode=app.PTree.SelectedNodes;
            try
                if CheckTreeNode(app,"MeasObj")
                    %clicked on something bellow it
                    project=app.PNodeSelected{2}.Parent;
                    if project.ID==app.PR.CurrentProject
                        
                        FillUITable(app.PNodeSelected{2},app.UITable,app.DropDownSelector.Value);
                    else
                        app.PR.CurrentProject=project.ID;
                        FillUITable(app.PNodeSelected{2},app.UITable,project.CurrentSelector);
                    end
                else
                    %clicked on project
                    project=app.PNodeSelected{2};
                    %GetTotalTable(app.PNodeSelected{2},app.UITable,app.DropDownSelector.Value);
                end
                InitiateSelectorCBX(app,project.CurrentSelector,project.ID);
                InfoUser(app,'status',['Node (' char(app.CurrNode.Text) ') selected']);
            catch ME
                InfoUser(app,'error',['Cant show this (' char(app.CurrNode.Text) ') measurement!']);
                %app.StatusLine.Text=['Error: Cant show this (' char(app.CurrNode.Text) ') measurement!'];
            end
        end

        % Menu selected function: HideprojectMenu
        function HideprojectMenuSelected(app, event)
            if app.PNodeSelected{1}>0
                while numel(app.PTree.SelectedNodes{1})>0
                    n=app.PTree.SelectedNodes(1).NodeData;
                    HideProject(app.PR,n);
                    InfoUser(app,'info',[app.PR.Projects(n).Name ' successfully hidden!']);
                    %app.StatusLine.Text=['-Info: ' app.PR.Projects(n).Name ' successfully hidden!'];
                end
            else
                InfoUser(app,'error','No project was selected to hide it!');               
            end
        end

        % Menu selected function: ShowprojectsMenu
        function ShowprojectsMenuSelected(app, event)
            ShowProjects(app.PR);
            %app.StatusLine.Text='-Info: All project are present in explorer';
            InfoUser(app,'info','All project are present in explorer'); 
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
%             indices = event.Indices;
%             newData = event.NewData;
            if CheckTreeNode(app,"MeasObj")
                Set=app.DropDownSelector.Value;
                SetSelector(app.PNodeSelected{2},event.Indices(1),event.NewData,Set);
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.UITabRow = event.Indices; 
            
            test=app.ShowWindowH;
            if isvalid(test)                
                if app.ShowWindowH.Open
                    [Tab]=Inspect(app.PNodeSelected{2},app.UITabRow(1));
                    FillTable(app.ShowWindowH,Tab);
                end
            else
                app.ShowWindowH=ShowData(app);
                return;
            end
        end

        % Menu selected function: ReloaddataMenu
        function ReloaddataMenuSelected(app, event)
            if CheckTreeNode(app,"MeasObj")
                try
                    ReLoadData(app.PNodeSelected{2}); 
                    InfoUser(app,'status','Succesfuly reloded data!'); 
                catch ME                    
                    InfoUser(app,'error','Coudlnt reload data, no folder was selected!'); 
                end
            else
                try
                    ReLoadData(app.PNodeSelected{2});
                    InfoUser(app,'status','Reloaded all data of project'); 
                catch ME
                    InfoUser(app,'error','Coudlnt reload data, no folder was selected!'); 
                end
                
            end
        end

        % Menu selected function: DeleteMenu
        function DeleteMenuSelected(app, event)
            if CheckTreeNode(app,"MeasObj")
                %app.CurrNode.Delete;
                MObj=app.PNodeSelected{2};
                PObj=app.PNodeSelected{3}.NodeData{2};
                n=app.PNodeSelected{1};
                node=app.CurrNode;
                DeleteM(PObj,n,MObj,node);
            else                
                InfoUser(app,'error','Measurement wasn''''t selected'); 
            end
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            app.UITabRow
            try
                if CheckTreeNode(app,"MeasObj")
                    [Tab]=Inspect(app.PNodeSelected{2},app.UITabRow(1));
                    FillTable(app.ShowWindowH,Tab);
                    InfoUser(app,'status','Succesfully shown a specimen data containers');
                else
                    InfoUser(app,'warning','Could start show window');
                end
                
            catch                
                InfoUser(app,'error','First select specimen which you want isnpect');
            end
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            if CheckTreeNode(app,"ProjectObj")
                node=app.PNodeSelected{2};
            else
                node=app.PNodeSelected{3}.NodeData{2};
            end
            AddSelector(node);
            n=app.PNodeSelected{1};
            InitiateSelectorCBX(app,n,node.ID); 
            
        end

        % Value changed function: DropDownSelector
        function DropDownSelectorValueChanged(app, event)
            if CheckTreeNode(app,"MeasObj") || CheckTreeNode(app,"ProjectObj")
                Sel = app.DropDownSelector.Value;
                if ischar(event.Value)
                    if CheckTreeNode(app,"ProjectObj")
                        %change selector name in ProjectObj 
                        ChangeSelName(app.PNodeSelected{2},event.PreviousValue,string(event.Value));
                        %app.PNodeSelected{3}.NodeData{2}.SelectorSets(event.PreviousValue).Description=event.Value;
                        %FillUITable(app.PNodeSelected{2},app.UITable,event.PreviousValue);
                        InitiateSelectorCBX(app,app.PNodeSelected{1});
                    else
                        %for meas
                        obj=app.PNodeSelected{3}.NodeData{2};
                        ChangeSelName(obj,event.PreviousValue,string(event.Value));
                        %app.PNodeSelected{3}.NodeData{2}.SelectorSets(event.PreviousValue).Description=event.Value;
                        FillUITable(app.PNodeSelected{2},app.UITable,event.PreviousValue);
                        InitiateSelectorCBX(app,app.PNodeSelected{3}.NodeData{1});
                    end
                else
                    if CheckTreeNode(app,"MeasObj")
                        FillUITable(app.PNodeSelected{2},app.UITable,Sel);
                        node=app.PNodeSelected{3}.NodeData{2};
                    else
                        %FillUITable(app.PNodeSelected{2},app.UITable,Sel);
                        node=app.PNodeSelected{2};
                    end
                    node.CurrentSelector=Sel;
                end

                %FillUITable(app.PNodeSelected{2},app.UITable,event.PreviousValue);
                %InitiateSelectorCBX(app,app.PNodeSelected{3}.NodeData{1});
            else
                InfoUser(app,'warning','First select correct emasurement for which you want change the selector set');                
            end
        end

        % Menu selected function: SaveworkMenu
        function SaveworkMenuSelected(app, event)
          try
              save(app.PR);
              InfoUser(app,'status','Work saved!');
          catch ME
              InfoUser(app,'error','Couldnt save work');
          end
        end

        % Button pushed function: DelButton
        function DelButtonPushed(app, event)
            nSet=app.DropDownSelector.Value;
            strSet=app.DropDownSelector.Items(nSet);
            MeaNode=[];
            if numel(app.DropDownSelector.Items)>1
                if app.DropDownSelector.Items{nSet}=="Default set"
                    
                else
                    if CheckTreeNode(app,"MeasObj")
                        ProjNode=app.PNodeSelected{3}.NodeData{2};  
                        MeaNode=app.PNodeSelected{2};
                    
                    else
                        ProjNode=app.PNodeSelected{2}; 
                    end
                    DeleteSel(ProjNode,nSet);
                    InitiateSelectorCBX(app,1,ProjNode.ID);
                    app.DropDownSelector.Value=1;
                    if ~isempty(MeaNode)
                        FillUITable(MeaNode,app.UITable,1);                    
                    end
                end
            else
                %its a last set, so dont erase it
            end        
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.ShowWindowH.delete;
            delete(app);            
        end

        % Menu selected function: EdittypesMenu
        function EdittypesMenuSelected(app, event)
            if CheckTreeNode(app,"MeasObj") || CheckTreeNode(app,"ProjectObj")
                if CheckTreeNode(app,"MeasObj")
                    InfoUser(app,'info','Editing data types only for measurement');
                    app.TypeSelWin=AppTypeSelector(app.PNodeSelected,app.MasterFolder,1);
                else
                    InfoUser(app,'info','Editing data types for whole project');
                    app.TypeSelWin=AppTypeSelector(app.PNodeSelected,app.MasterFolder,2);
                end
            else
                InfoUser(app,'error','Please select either project or measurement node');
            end
        end

        % Menu selected function: ReadDataMenu
        function ReadDataMenuSelected(app, event)
            if CheckTreeNode(app,"MeasObj")
                InfoUser(app,'warning',['Reading for measurmenet: (' char(app.CurrNode.Text) ')']);
                DL=DataLoader(app.MasterFolder);
                
                SetBruteFolder(DL,'H:\Google drive\GitHub\ProjectExplorer\Main\Client\TestingData\');
                SetDataTypes(DL,app.PNodeSelected{2}.DataTypesTable);
                ReadData(DL);
            else
                InfoUser(app,'warning','Select specific measurement node and then hit ReadData');
            end
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;
            
        end

        % Callback function
        function CODE_NAME12ValueChanged(app, event)
            type = app.DropDown.Value;
            Sel = app.DropDownSelector.Value;
            value=char(app.DropDownSelector.Items(Sel));
            
            app.DropDown.Value='-';
            %Sel=find(string(app.DropDownSelector.Items)==string(TMP));
            %Sel=TMP;
            if Sel>0 & isnumeric(Sel)
                if CheckTreeNode(app,"ProjectObj")
                    node=app.PNodeSelected{2};
                else
                    %meas
                    node=app.PNodeSelected{3}.NodeData{2};
                end
                Stack(node,Sel);
                app.FP.GraphFolder='E:\Google Drive\Škola\Měření\2020\GA_cementotřískové desky_AE\Grafy\AllTogether\';
                if value~='-'
                    PutData(app.FP,node);
                    Plot(app.FP,type,value);
                end
            end
            
        end

        % Menu selected function: ExportoverviewMenu
        function ExportoverviewMenuSelected(app, event)
            
            if CheckTreeNode(app,"ProjectObj")
                node=app.PNodeSelected{2};
            else
                node=app.PNodeSelected{3}.NodeData{2};
            end
            Out=MakeOverView(node);
        end

        % Menu selected function: ResetselectorsMenu
        function ResetselectorsMenuSelected(app, event)
            if CheckTreeNode(app,"ProjectObj")
                node=app.PNodeSelected{2};
            else
                node=app.PNodeSelected{3}.NodeData{2};
            end
            ResetSelectors(node);
        end

        % Menu selected function: DeleteprojectMenu
        function DeleteprojectMenuSelected(app, event)
            if CheckTreeNode(app,"ProjectObj")
                node=app.PNodeSelected{2};
            else
                node=app.PNodeSelected{3}.NodeData{2};
            end
            DeleteProject(app.PR,node.ID)
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            type = app.DropDown.Value;
            Sel = app.DropDownSelector.Value;
            value=char(app.DropDownSelector.Items(Sel));
            
            app.DropDown.Value='-';
            %Sel=find(string(app.DropDownSelector.Items)==string(TMP));
            %Sel=TMP;
            if Sel>0 & isnumeric(Sel)
                if CheckTreeNode(app,"ProjectObj")
                    node=app.PNodeSelected{2};
                else
                    %meas
                    node=app.PNodeSelected{3}.NodeData{2};
                end
                Stack(node,Sel);
                app.FP.GraphFolder='E:\Google Drive\Škola\Měření\2020\GA_cementotřískové desky_AE\Grafy\AllTogether\';
                if value~='-'
                    PutData(app.FP,node);
                    Plot(app.FP,type,value);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ProjectMenu = uimenu(app.UIFigure);
            app.ProjectMenu.Text = 'Project';
            app.NewProjectMenu = uimenu(app.ProjectMenu);
            app.NewProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @NewProjectMenuSelected, true);
            app.NewProjectMenu.Text = 'New Project';
            app.DeleteprojectMenu = uimenu(app.ProjectMenu);
            app.DeleteprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteprojectMenuSelected, true);
            app.DeleteprojectMenu.Text = 'Delete project';
            app.OptionsMenu = uimenu(app.ProjectMenu);
            app.OptionsMenu.MenuSelectedFcn = createCallbackFcn(app, @OptionsMenuSelected, true);
            app.OptionsMenu.Text = 'Options';
            app.SaveworkMenu = uimenu(app.ProjectMenu);
            app.SaveworkMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveworkMenuSelected, true);
            app.SaveworkMenu.Text = 'Save work';
            app.ProjectoperationMenu = uimenu(app.ProjectMenu);
            app.ProjectoperationMenu.Text = 'Project operation';
            app.ExportoverviewMenu = uimenu(app.ProjectoperationMenu);
            app.ExportoverviewMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportoverviewMenuSelected, true);
            app.ExportoverviewMenu.Text = 'Export overview';
            app.ResetselectorsMenu = uimenu(app.ProjectoperationMenu);
            app.ResetselectorsMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetselectorsMenuSelected, true);
            app.ResetselectorsMenu.Text = 'Reset selectors';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.HideprojectMenu = uimenu(app.EditMenu);
            app.HideprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @HideprojectMenuSelected, true);
            app.HideprojectMenu.Text = 'Hide project';
            app.ShowprojectsMenu = uimenu(app.EditMenu);
            app.ShowprojectsMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowprojectsMenuSelected, true);
            app.ShowprojectsMenu.Text = 'Show projects';
            app.EdittypesMenu = uimenu(app.EditMenu);
            app.EdittypesMenu.MenuSelectedFcn = createCallbackFcn(app, @EdittypesMenuSelected, true);
            app.EdittypesMenu.Text = 'Edit types';
            app.MesurementsMenu = uimenu(app.UIFigure);
            app.MesurementsMenu.Text = 'Mesurements';
            app.NewmeasuremnetMenu = uimenu(app.MesurementsMenu);
            app.NewmeasuremnetMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMeasurementMenuSelected, true);
            app.NewmeasuremnetMenu.Text = 'New measuremnet';
            app.EditmeasurementMenu = uimenu(app.MesurementsMenu);
            app.EditmeasurementMenu.Text = 'Edit measurement';
            app.ReloaddataMenu = uimenu(app.EditmeasurementMenu);
            app.ReloaddataMenu.MenuSelectedFcn = createCallbackFcn(app, @ReloaddataMenuSelected, true);
            app.ReloaddataMenu.Text = 'Reload data';
            app.DeleteMenu = uimenu(app.EditmeasurementMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteMenuSelected, true);
            app.DeleteMenu.Text = 'Delete';
            app.ReadDataMenu = uimenu(app.MesurementsMenu);
            app.ReadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ReadDataMenuSelected, true);
            app.ReadDataMenu.Text = 'ReadData';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.PTree = uitree(app.GridLayout);
            app.PTree.Multiselect = 'on';
            app.PTree.SelectionChangedFcn = createCallbackFcn(app, @ProjectSelectLMB, true);
            app.StatusLine = uilabel(app.GridLayout);
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = 'Panel';
            app.DropDownLabel = uilabel(app.Panel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.DropDownSelector = uidropdown(app.GridLayout);
            app.DropDownSelector.Editable = 'on';
            app.DropDownSelector.ValueChangedFcn = createCallbackFcn(app, @DropDownSelectorValueChanged, true);
            app.NewButton = uibutton(app.GridLayout, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New';
            app.DelButton = uibutton(app.GridLayout, 'push');
            app.DelButton.ButtonPushedFcn = createCallbackFcn(app, @DelButtonPushed, true);
            app.DelButton.Text = 'Del';
            app.ShowButton = uibutton(app.GridLayout, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.SystemUsage = uilabel(app.GridLayout);
            app.SystemUsage.Text = 'SystemUsage';
            app.SetLabel = uilabel(app.GridLayout);
            app.SetLabel.Text = 'Set:';
            app.ProjectstructureLabel = uilabel(app.GridLayout);
            app.ProjectstructureLabel.Text = 'Project structure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectExplorer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end