classdef SetOptions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        TabGroup             matlab.ui.container.TabGroup
        PathsTab             matlab.ui.container.Tab
        RemainscharsLabel    matlab.ui.control.Label
        SandBoxButton        matlab.ui.control.Button
        SandBoxpathLabel     matlab.ui.control.Label
        MasterPathLabel      matlab.ui.control.Label
        GraphpathLabel       matlab.ui.control.Label
        RemainscharsLabel_2  matlab.ui.control.Label
        SandBoxButton_2      matlab.ui.control.Button
        SaveandexitButton    matlab.ui.control.Button
    end

    
    properties (Access = public)
        UserOptions struct; % Description
        MotherApp; %handle of mother app
        CurrentID;
    end
    
    methods (Access = private)
        
        function LoadOption(app)
            app.MasterPathLabel.Text=['Master path: ' app.UserOptions.MasterFolder];
            app.RemainscharsLabel.Text=sprintf('Remains %d chars',260-length(app.UserOptions.SandBoxFolder));
            app.SandBoxpathLabel.Text=['SandBox path: ' app.UserOptions.SandBoxFolder];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MotherApp)
            app.CurrentID=MotherApp.UserID;
            app.UserOptions=MotherApp.US.UserOptions(app.CurrentID);
            app.MotherApp=MotherApp;            
            LoadOption(app);
        end

        % Button pushed function: SandBoxButton
        function CreateSandBox(app, event)
            SandBoxFolder=uigetdir(cd,'Select folder for saving all work');
            if SandBoxFolder==0
                app.UserOptions.SandBoxFolder='none';
            else
                app.UserOptions.SandBoxFolder=[SandBoxFolder '\'];
                app.MotherApp.US.UserOptions(app.CurrentID).SandBoxFolder=app.UserOptions.SandBoxFolder;
            end

            app.RemainscharsLabel.Text=sprintf('Remains %d chars',260-length(app.UserOptions.SandBoxFolder));
            app.SandBoxpathLabel.Text=['SandBox path: ' app.UserOptions.SandBoxFolder];
        end

        % Button pushed function: SaveandexitButton
        function SaveAndExit(app, event)
            app.MotherApp.US.UserOptions(app.CurrentID)=app.UserOptions;
            SaveUserOptions(app.MotherApp.US);
            app.MotherApp.US=CheckUser(app.MotherApp.US);
            LoadProjects(app.MotherApp);
            app.MotherApp.UIFigure.Visible='on';            
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.PathsTab = uitab(app.TabGroup);
            app.PathsTab.Title = 'Paths';
            app.RemainscharsLabel = uilabel(app.PathsTab);
            app.RemainscharsLabel.Text = 'Remains # chars';
            app.SandBoxButton = uibutton(app.PathsTab, 'push');
            app.SandBoxButton.ButtonPushedFcn = createCallbackFcn(app, @CreateSandBox, true);
            app.SandBoxButton.Text = 'Select';
            app.SandBoxpathLabel = uilabel(app.PathsTab);
            app.SandBoxpathLabel.Text = 'SandBox path:';
            app.MasterPathLabel = uilabel(app.PathsTab);
            app.MasterPathLabel.Text = 'Master path:';
            app.GraphpathLabel = uilabel(app.PathsTab);
            app.GraphpathLabel.Text = 'Graph path:';
            app.RemainscharsLabel_2 = uilabel(app.PathsTab);
            app.RemainscharsLabel_2.Text = 'Remains # chars';
            app.SandBoxButton_2 = uibutton(app.PathsTab, 'push');
            app.SandBoxButton_2.Text = 'Select';
            app.SaveandexitButton = uibutton(app.GridLayout, 'push');
            app.SaveandexitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAndExit, true);
            app.SaveandexitButton.Text = 'Save and exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SetOptions(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end