classdef AppTypeSelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        UITable                       matlab.ui.control.Table
        NewButton                     matlab.ui.control.Button
        DelButton                     matlab.ui.control.Button
        CloneButton                   matlab.ui.control.Button
        ClonnedCheckBox               matlab.ui.control.CheckBox
        SaveButton                    matlab.ui.control.Button
        CloseButton                   matlab.ui.control.Button
        QuickOperationDropDownLabel   matlab.ui.control.Label
        QuickOperationDropDown        matlab.ui.control.DropDown
        ExperimentnameEditFieldLabel  matlab.ui.control.Label
        ExperimentnameEditField       matlab.ui.control.EditField
        SettingsofselectedcontainersLabel  matlab.ui.control.Label
        ContainerOptions              matlab.ui.container.TabGroup
        TypeTab                       matlab.ui.container.Tab
        ContainerTab                  matlab.ui.container.Tab
    end

    
    properties (Access = public)
        ProjObj;
        MeaObj;
        MasterFolder;
        TypesTable table;
        DataTypes=[];
        IconFolder char; % Description
        ContainerTypes categorical;
        SufixTypes categorical;
        StrDataTypes categorical; 
        CurrRowSel (1,1) double;
        CurrRowSelOld (1,1) double;
        nType (1,1) double;
        StartType;
        Parent;
        TreeNode;
        MainObj;
        DrawNodeHan;
    end

    
    methods (Access = private)
        
        function SetOptions(app)
            app.StrDataTypes=OperLib.GetTypes;
            app.ContainerTypes=OperLib.GetContainerTypes;
            app.SufixTypes=OperLib.GetSuffixTypes;
        end
        
        function AddRow(app)
            row=size(app.UITable.Data,1)+1;
            
            app.nType=numel(app.DataTypes)+1;
            app.DataTypes{app.nType}=MainTable(app.Parent);
            
            SetContainer(app,app.nType);

            SetGuiParent(app.DataTypes{app.nType},app.ContainerOptions);
            
            InitTypeOption(app.DataTypes{numel(app.DataTypes)});
%             InitContainerOption(app.DataTypes{numel(app.DataTypes)},'type');
        end
        
        function CheckType(app)
            T=string(app.UITable.Data{:,3});
            arr=OperLib.GetSuffixTypes;
            arrstr=string(arr);
            
            for i=1:size(T,1)
                idx=find(arrstr==T(i,1));
                out(i,1)=arr(idx);
            end
            app.UITable.Data=[app.UITable.Data(:,1:2),table(out,'VariableNames',{'Sufix'}),app.UITable.Data(:,4)];
%             app.UITable.Data.Properties.VariableNames={'Container','KeyWord','Sufix','DataType'};
        end
        
        function SetContainer(app,Idx)
            obj=app.DataTypes{Idx};
            DataType=OperLib.GetTypes(class(obj));
            CheckType(app);
            
            T=table(obj.ContainerType,obj.KeyWord,obj.Sufix,DataType(1),...
                'VariableNames',{'Container','KeyWord','Sufix','DataType'});            

            if Idx<=size(app.UITable.Data,1)
                app.UITable.Data(Idx,:)=T;
            else
                app.UITable.Data=[app.UITable.Data; T];
            end
        end
        
        function SetType(app,Idx,StrDataType)
            if ~isa(app.DataTypes{Idx},char(StrDataType))
                app.DataTypes{Idx}=OperLib.CreateType(StrDataType,app.Parent);
                SetContainer(app,Idx);
                
            end
            SetGuiParent(app.DataTypes{Idx},app.ContainerOptions);
            InitTypeOption(app.DataTypes{Idx});
%             DrawTypeOption(app.DataTypes{Idx});
        end
        
        function GiveGuiParent(app)
            for i =1:numel(app.DataTypes)
                app.nType=app.nType+1;
                SetGuiParent(app.DataTypes{i},app.ContainerOptions);
                
            end
        end


        function LoadData2Tables(app)
            if app.StartType==1
                if app.MeaObj.ClonedTypes==true
                    app.UITable.Data=app.ProjObj.TypeTable(:,1:end-1);
                    app.DataTypes=table2array(app.ProjObj.TypeTable(:,end))';
                else
                    app.UITable.Data=app.MeaObj.TypeTable(:,1:end-1);
                    app.DataTypes=table2array(app.MeaObj.TypeTable(:,end))';
                end
            else
                app.UITable.Data=app.ProjObj.DataTypesTable(:,1:end-1);
                app.DataTypes=table2array(app.ProjObj.DataTypesTable(:,end))';
            end
            GiveGuiParent(app);
        end
        
        function OutTable = GetOutTable(app)
            Types=app.DataTypes;
            for i=1:numel(Types)
                ClearGUI(Types{i});
                Types{i}.Init=false;
            end
            OutTable=[app.UITable.Data, table(Types','Variablenames',{'TypesObj'})];
            SetTypeSettings(app.Parent,OutTable);
        end
        
        function LoadTypeSettings(app)
            TypeSettings=app.Parent.TypeSettings;
            if ~isempty(TypeSettings)
                app.DataTypes=TypeSettings{:,end}';
                for i=1:numel(app.DataTypes)
                    SetGuiParent(app.DataTypes{i},app.ContainerOptions);
                end
                app.UITable.Data=TypeSettings(:,1:end-1);
                app.ExperimentnameEditField.Value=app.Parent.Name;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, obj)


            app.Parent=obj;      
            app.MasterFolder=obj.Parent.Parent.Parent.Parent.MasterFolder;
            app.DrawNodeHan=obj.DrawNode;
            
            app.UIFigure.Name='Type selector table';
            SetOptions(app);
            
            app.UITable.ColumnEditable=true;
            
            app.IconFolder=[app.MasterFolder '\Master\GUI\Icons\'];
            
            app.NewButton.Icon=[app.IconFolder 'plus_sign.gif'];
            app.NewButton.Text='';
            
            app.DelButton.Icon=[app.IconFolder 'cancel_sign.gif'];
            app.DelButton.Text='';
            app.nType=0;
            
            LoadTypeSettings(app);
        end

        % Callback function: CloseButton, UIFigure
        function UIFigureCloseRequest(app, event)
            for i=1:numel(app.DataTypes)
                ClearGUI(app.DataTypes{i});
            end
            app.Parent.TypeFig=[];
            delete(app);
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)

            AddRow(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            for i=1:numel(app.DataTypes)
                ClearGUI(app.DataTypes{i});
            end
            OutTable = GetOutTable(app);
            
            delete(app);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            Col=event.Indices(2);
            Row=event.Indices(1);
            
            app.CurrRowSel=Row;
            if app.CurrRowSelOld==0
                app.CurrRowSelOld=app.CurrRowSel;
                InitTypeOption(app.DataTypes{app.CurrRowSel});
            else
                if app.CurrRowSelOld==app.CurrRowSel
                    
                else
                    if app.CurrRowSelOld<=numel(app.DataTypes)
                        HideComponents(app.DataTypes{app.CurrRowSelOld});
                    end
                    InitTypeOption(app.DataTypes{app.CurrRowSel});
                end
            end
            
            
            app.CurrRowSelOld=app.CurrRowSel;
        end

        % Button pushed function: DelButton
        function DelButtonPushed(app, event)
            if app.CurrRowSel>0
                app.UITable.Data(app.CurrRowSel,:)=[];
                app.DataTypes(app.CurrRowSel)=[];
                app.CurrRowSel=0;
                app.CurrRowSelOld=0;
                app.nType=numel(app.DataTypes);
            end
        end

        % Button pushed function: CloneButton
        function CloneButtonPushed(app, event)
            if app.StartType==1
                app.ClonnedCheckBox.Value=1;
                app.MeaObj.ClonedTypes=1;
                LoadData2Tables(app);
            end
        end

        % Value changed function: QuickOperationDropDown
        function QuickOperationDropDownValueChanged(app, event)
            value = app.QuickOperationDropDown.Value;
            switch lower(value)
                case '1' %load table
                    [file,path] = uigetfile('*.mat');
                    load([path '\' file]);
                    %OutTable=[app.UITable.Data, table(app.DataTypes','Variablenames',{'TypesObj'})];
                    PT=OutTable(:,1:end-1);
                    app.UITable.Data=PT;
                    TMP=table2array(OutTable(:,end))';
                    app.DataTypes=TMP;
                    
                    for i=1:numel(app.DataTypes)
                        SetGuiParent(app.DataTypes{i},app.ContainerOptions);
                    end
                case '2' %export tble
                    [file,path,indx] = uiputfile('animinit.mat');
                    for i=1:numel(app.DataTypes)
                        app.DataTypes{i}.Init=false;
                    end
                    

                    OutTable = GetOutTable(app);
         
                    try
                        save([path file],"OutTable")
                    catch ME
                        
                    end
                    
                    for i=1:numel(app.DataTypes)
                        app.DataTypes{i}.Init=true;
                    end
                case '3' %force table
                    if app.StartType==2
                        OutTable = GetOutTable(app);
                        CloneDataType(app.ProjObj,OutTable);
                    end
                otherwise
               
            end
            app.QuickOperationDropDown.Value='0';
        end

        % Value changed function: ExperimentnameEditField
        function ExperimentnameEditFieldValueChanged(app, event)
            value = app.ExperimentnameEditField.Value;
            app.Parent.TreeNode.Text=value;
            app.Parent.Name=value;
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            Col=event.InteractionColumn;
            Row=app.CurrRowSel;
            obj=app.DataTypes{Row};
            
            newData = app.UITable.DisplayData{Row,Col};
            switch Col
                case 1
                    SetConType(obj,newData);
                case 2
                    SetKeyWord(obj,newData);
                case 3
                    SetSuffix(obj,newData);
                case 4
                    SetType(app,Row,newData);
                otherwise                    
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Container'; 'KeyWord'; 'Sufix'; 'DataType'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.NewButton = uibutton(app.GridLayout, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New';
            app.DelButton = uibutton(app.GridLayout, 'push');
            app.DelButton.ButtonPushedFcn = createCallbackFcn(app, @DelButtonPushed, true);
            app.DelButton.Text = 'Del';
            app.CloneButton = uibutton(app.GridLayout, 'push');
            app.CloneButton.ButtonPushedFcn = createCallbackFcn(app, @CloneButtonPushed, true);
            app.CloneButton.Text = 'Clone';
            app.ClonnedCheckBox = uicheckbox(app.GridLayout);
            app.ClonnedCheckBox.Enable = 'off';
            app.ClonnedCheckBox.Text = 'Clonned';
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CloseButton = uibutton(app.GridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CloseButton.Text = 'Close';
            app.QuickOperationDropDownLabel = uilabel(app.GridLayout);
            app.QuickOperationDropDownLabel.Text = 'Quick Operation';
            app.QuickOperationDropDown = uidropdown(app.GridLayout);
            app.QuickOperationDropDown.Items = {'-', 'Load table', 'Export table', 'Force TypeTable 2 all'};
            app.QuickOperationDropDown.ItemsData = {'0', '1', '2', '3'};
            app.QuickOperationDropDown.ValueChangedFcn = createCallbackFcn(app, @QuickOperationDropDownValueChanged, true);
            app.QuickOperationDropDown.Value = '0';
            app.ExperimentnameEditFieldLabel = uilabel(app.GridLayout);
            app.ExperimentnameEditFieldLabel.Text = 'Experiment name:';
            app.ExperimentnameEditField = uieditfield(app.GridLayout, 'text');
            app.ExperimentnameEditField.ValueChangedFcn = createCallbackFcn(app, @ExperimentnameEditFieldValueChanged, true);
            app.ExperimentnameEditField.Value = '--#New Experiment#--';
            app.SettingsofselectedcontainersLabel = uilabel(app.GridLayout);
            app.SettingsofselectedcontainersLabel.Text = 'Settings of selected containers:';
            app.ContainerOptions = uitabgroup(app.GridLayout);
            app.TypeTab = uitab(app.ContainerOptions);
            app.TypeTab.Title = 'Type';
            app.ContainerTab = uitab(app.ContainerOptions);
            app.ContainerTab.Title = 'Container';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppTypeSelector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end