classdef AppTypeSelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        UITable                       matlab.ui.control.Table
        NewButton                     matlab.ui.control.Button
        DelButton                     matlab.ui.control.Button
        CloneButton                   matlab.ui.control.Button
        ClonnedCheckBox               matlab.ui.control.CheckBox
        SaveButton                    matlab.ui.control.Button
        CloseButton                   matlab.ui.control.Button
        QuickOperationDropDownLabel   matlab.ui.control.Label
        QuickOperationDropDown        matlab.ui.control.DropDown
        ExperimentnameEditFieldLabel  matlab.ui.control.Label
        ExperimentnameEditField       matlab.ui.control.EditField
        SettingsofselectedcontainersLabel  matlab.ui.control.Label
        ContainerOptions              matlab.ui.container.TabGroup
        TypeTab                       matlab.ui.container.Tab
        ContainerTab                  matlab.ui.container.Tab
    end

    
    properties (Access = public)
        ProjObj;
        MeaObj;
        MasterFolder;
        TypesTable table;
        DataTypes=[];
        IconFolder char; % Description
        ContainerTypes categorical;
        SufixTypes categorical;
        StrDataTypes categorical; 
        CurrRowSel (1,1) double;
        nType (1,1) double;
        StartType;
        Parent;
        TreeNode;
        MainObj;
        DrawNodeHan;
    end

    
    methods (Access = private)
        
        function SetOptions(app)
            app.StrDataTypes=OperLib.GetTypes;
            app.ContainerTypes=OperLib.GetContainerTypes;
            app.SufixTypes=OperLib.GetSuffixTypes;
        end
        
        function AddRow(app)
            row=size(app.UITable.Data,1)+1;
            
            app.nType=app.nType+1;
            app.DataTypes{app.nType}=MainTable;
            obj=app.DataTypes{app.nType};
            %app.DataTypes{app.nType}.ContainerType
            %app.DataTypes{app.nType}.KeyWord
            %app.DataTypes{app.nType}.Sufix
%             Container=app.ContainerTypes(1);            
%             KeyWord="";
%             Sufix=app.SufixTypes(1);
            DataType=class(app.DataTypes{app.nType});
            
            
            T=table(obj.ContainerType,obj.KeyWord,obj.Sufix,DataType,...
                'VariableNames',{'Container','KeyWord','Sufix','DataType'});
            
            if isa(app.UITable.Data,'table')
                app.UITable.Data=[app.UITable.Data; T];
            else
                app.UITable.Data=T;
            end
            

%             app.DataTypes(app.nType)=MainTable;
            SetGuiParent(app.DataTypes{app.nType},app.ContainerOptions);
            DrawTypeOption(app.DataTypes{app.nType});
        end
        
        function SetType(app,Idx,StrDataType)
            
            if ~isa(app.DataTypes{Idx},char(StrDataType))
                app.DataTypes{Idx}=OperLib.CreateType(StrDataType);
            end
            SetGuiParent(app.DataTypes{Idx},app.ContainerOptions);
        end
        
        function GiveGuiParent(app)
            for i =1:numel(app.DataTypes)
                app.nType=app.nType+1;
                SetGuiParent(app.DataTypes{i},app.ContainerOptions);
            end
        end


        function LoadData2Tables(app)
            if app.StartType==1
                if app.MeaObj.ClonedTypes==true
                    app.UITable.Data=app.ProjObj.TypeTable(:,1:end-1);
                    app.DataTypes=table2array(app.ProjObj.TypeTable(:,end))';
                else
                    app.UITable.Data=app.MeaObj.TypeTable(:,1:end-1);
                    app.DataTypes=table2array(app.MeaObj.TypeTable(:,end))';
                end
            else
                app.UITable.Data=app.ProjObj.DataTypesTable(:,1:end-1);
                app.DataTypes=table2array(app.ProjObj.DataTypesTable(:,end))';
            end
            GiveGuiParent(app);
        end
        
        function OutTable = GetOutTable(app)
            OutTable=[app.UITable.Data, table(app.DataTypes','Variablenames',{'TypesObj'})];
            SetTypeSettings(app.Parent,OutTable);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, obj)


            app.Parent=obj;      
            app.MasterFolder=obj.Parent.Parent.Parent.Parent.MasterFolder;
            app.DrawNodeHan=obj.DrawNode;
            
            app.UIFigure.Name='Type selector table';
            SetOptions(app);
            
            app.UITable.ColumnEditable=true;
            
            app.IconFolder=[app.MasterFolder '\Master\GUI\Icons\'];
            
            app.NewButton.Icon=[app.IconFolder 'plus_sign.gif'];
            app.NewButton.Text='';
            
            app.DelButton.Icon=[app.IconFolder 'cancel_sign.gif'];
            app.DelButton.Text='';
            app.nType=0;
            
%             if app.Parent.Parent.Status.Value==1
%                 app.UITable.Data=ExpHan.TypeSettings(:,1:end-1);
%                 app.ExperimentnameEditField.Value=ExpHan.Name;
%                 app.DataTypes=ExpHan.TypeSettings{:,end}';
%             else
%                 
%             end
            
%             AddParent(app.DrawNodeHan,app.TypeTab,'type');
%             AddParent(app.DrawNodeHan,app.ContainerTab,'cont');

        end

        % Callback function: CloseButton, UIFigure
        function UIFigureCloseRequest(app, event)

            delete(app);
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)

            AddRow(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            OutTable = GetOutTable(app);
            
%             if app.StartType==1
%                 SetDataTypes(app.MeaObj,OutTable);
%             else
%                 SetDataTypesTable(app.ProjObj,OutTable);
%             end
            
            delete(app);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            
            
            if app.CurrRowSel>0
                app.CurrRowSel= event.Indices(1);                     
            else
                app.CurrRowSel= 1; 
            end
            
            if event.Indices(2)==4
                SetType(app,app.CurrRowSel,app.UITable.Data{app.CurrRowSel,4});
            end
            CurrType=app.DataTypes{app.CurrRowSel};
            DrawTypeOption(CurrType);
        end

        % Button pushed function: DelButton
        function DelButtonPushed(app, event)
            if app.CurrRowSel>0
                app.UITable.Data(app.CurrRowSel,:)=[];
                app.DataTypes{app.CurrRowSel}=[];
                app.nType=app.nType-1;
            end
        end

        % Button pushed function: CloneButton
        function CloneButtonPushed(app, event)
            if app.StartType==1
                app.ClonnedCheckBox.Value=1;
                app.MeaObj.ClonedTypes=1;
                LoadData2Tables(app);
            end
        end

        % Value changed function: QuickOperationDropDown
        function QuickOperationDropDownValueChanged(app, event)
            value = app.QuickOperationDropDown.Value;
            switch lower(value)
                case '1' %load table
                    [file,path] = uigetfile('*.mat');
                    load([path '\' file]);
                    %OutTable=[app.UITable.Data, table(app.DataTypes','Variablenames',{'TypesObj'})];
                    PT=OutTable(:,1:end-1);
                    app.UITable.Data=PT;
                    TMP=table2array(OutTable(:,end))';
                    app.DataTypes=TMP;
                case '2' %export tble
                    [file,path,indx] = uiputfile('animinit.mat');
                    OutTable = GetOutTable(app);
                    try
                        save([path file],"OutTable")
                    catch ME
                        
                    end
                case '3' %force table
                    if app.StartType==2
                        OutTable = GetOutTable(app);
                        CloneDataType(app.ProjObj,OutTable);
                    end
                otherwise
               
            end
            app.QuickOperationDropDown.Value='0';
        end

        % Value changed function: ExperimentnameEditField
        function ExperimentnameEditFieldValueChanged(app, event)
            value = app.ExperimentnameEditField.Value;
            app.Parent.TreeNode.Text=value;
            app.Parent.Name=value;
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            Col=event.InteractionColumn;
            Row=app.CurrRowSel;
            obj=app.DataTypes{Row};
            
            newData = app.UITable.DisplayData{Row,Col};
            switch Col
                case 1
                    SetConType(obj,newData);
                case 2
                    SetKeyWord(obj,newData);
                case 3
                    SetSuffix(obj,newData);
                otherwise                    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Container'; 'KeyWord'; 'Sufix'; 'DataType'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.NewButton = uibutton(app.GridLayout, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New';
            app.DelButton = uibutton(app.GridLayout, 'push');
            app.DelButton.ButtonPushedFcn = createCallbackFcn(app, @DelButtonPushed, true);
            app.DelButton.Text = 'Del';
            app.CloneButton = uibutton(app.GridLayout, 'push');
            app.CloneButton.ButtonPushedFcn = createCallbackFcn(app, @CloneButtonPushed, true);
            app.CloneButton.Text = 'Clone';
            app.ClonnedCheckBox = uicheckbox(app.GridLayout);
            app.ClonnedCheckBox.Enable = 'off';
            app.ClonnedCheckBox.Text = 'Clonned';
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CloseButton = uibutton(app.GridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CloseButton.Text = 'Close';
            app.QuickOperationDropDownLabel = uilabel(app.GridLayout);
            app.QuickOperationDropDownLabel.Text = 'Quick Operation';
            app.QuickOperationDropDown = uidropdown(app.GridLayout);
            app.QuickOperationDropDown.Items = {'-', 'Load table', 'Export table', 'Force TypeTable 2 all'};
            app.QuickOperationDropDown.ItemsData = {'0', '1', '2', '3'};
            app.QuickOperationDropDown.ValueChangedFcn = createCallbackFcn(app, @QuickOperationDropDownValueChanged, true);
            app.QuickOperationDropDown.Value = '0';
            app.ExperimentnameEditFieldLabel = uilabel(app.GridLayout);
            app.ExperimentnameEditFieldLabel.Text = 'Experiment name:';
            app.ExperimentnameEditField = uieditfield(app.GridLayout, 'text');
            app.ExperimentnameEditField.ValueChangedFcn = createCallbackFcn(app, @ExperimentnameEditFieldValueChanged, true);
            app.ExperimentnameEditField.Value = 'name xy';
            app.SettingsofselectedcontainersLabel = uilabel(app.GridLayout);
            app.SettingsofselectedcontainersLabel.Text = 'Settings of selected containers:';
            app.ContainerOptions = uitabgroup(app.GridLayout);
            app.TypeTab = uitab(app.ContainerOptions);
            app.TypeTab.Title = 'Type';
            app.ContainerTab = uitab(app.ContainerOptions);
            app.ContainerTab.Title = 'Container';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppTypeSelector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end