classdef ProjectPlotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        DataMenu               matlab.ui.container.Menu
        ShowdatanodeMenu       matlab.ui.container.Menu
        SaveplotsMenu          matlab.ui.container.Menu
        ExportplotMenu         matlab.ui.container.Menu
        ExportallplotsMenu     matlab.ui.container.Menu
        PlotsMenu              matlab.ui.container.Menu
        AddnewplotMenu         matlab.ui.container.Menu
        xyMenu                 matlab.ui.container.Menu
        xyy2Menu               matlab.ui.container.Menu
        xyx2y2Menu             matlab.ui.container.Menu
        TensileShearMenu       matlab.ui.container.Menu
        DeletecurrentplotMenu  matlab.ui.container.Menu
        LegendMenu             matlab.ui.container.Menu
        GridLayout             matlab.ui.container.GridLayout
        TabGroup               matlab.ui.container.TabGroup
        Tab                    matlab.ui.container.Tab
        GridLayout2            matlab.ui.container.GridLayout
        UIAxes                 matlab.ui.control.UIAxes
        DropDown               matlab.ui.control.DropDown
        Panel                  matlab.ui.container.Panel
        GridLayout3            matlab.ui.container.GridLayout
        Label                  matlab.ui.control.Label
        EditFieldLabel         matlab.ui.control.Label
        EditField              matlab.ui.control.EditField
        EditField_2Label       matlab.ui.control.Label
        EditField_2            matlab.ui.control.EditField
        EditField_3Label       matlab.ui.control.Label
        EditField_3            matlab.ui.control.EditField
        ButtonGroup            matlab.ui.container.ButtonGroup
        Button                 matlab.ui.control.ToggleButton
        Button2                matlab.ui.control.ToggleButton
        Button3                matlab.ui.control.ToggleButton
        NewButton              matlab.ui.control.Button
        CloseButton            matlab.ui.control.Button
        UITable                matlab.ui.control.Table
    end

    
    properties (Access = public)
        Axes; %1-n axis created by user
        Tabs; %2-n tabs created by user
        CurrTab; %number of current tab opened
        TabIdx;
        DataCore; %datacore retrived from project explorer
        Presets; %tables of setting created for each plot
        PEH; %project explorer handle
        PlotObj; %object for plotting in specific tab with its table
        CurrSell;
    end
    
    methods (Access = private)
        
        function DrawAxis(app,tab)  
            if tab>numel(app.Tabs)
                app.Tabs{tab}=uitab(app.TabGroup);
            end
            app.Tabs{tab}.Title=['Plot ' char(num2str(tab))];
            app.Tabs{tab}.UserData=tab;
            
            Dim=app.Tabs{tab}.InnerPosition;
            pause(2);
            app.Axes{tab}=uiaxes(app.Tabs{tab},'Position',Dim);
            app.TabGroup.SelectedTab = app.Tabs{tab};
            app.CurrTab=app.Tabs{tab};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, PEH)
            app.UIFigure.Visible='off';
            app.PEH=PEH;
            app.CurrSell=PEH.CurrSell;
            
            app.DataCore=app.PEH.DataCore;
            app.Tabs{1} = app.Tab;
            
            %pause(7);        
            pause(3);
            DrawAxis(app,1);            
            app.PlotObj=PlotterObj(app.Axes{1},app.Panel,app);
            app.UIFigure.Visible='on';
            
            Test(app.DataCore,app.Axes{1},app.CurrSell)
%            [x,y]=GetData(app.DataCore);
           
 %           Plot(app.PlotObj,x,y,'line');
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.PEH.UIFigure.Visible='on';
            delete(app);
            
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            if numel(app.Tabs)<11
                DrawAxis(app,numel(app.Tabs)+1)
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            if numel(app.Tabs)>1
                OldTabIdx=0;
                for i=1:numel(app.Tabs)
                    if strcmp(string(app.Tabs{i}.Title),string(app.CurrTab.Title))
                        OldTabIdx=i; 
                    else
                        if OldTabIdx>0
                            app.Tabs{i}.Title=['Plot ' char(num2str(i-1))];
                         end
                    end
                end
                
                if OldTabIdx>1
                    NewTabNum=OldTabIdx-1;
                    app.Tabs(OldTabIdx)=[];
                    app.TabGroup.SelectedTab = app.Tabs{NewTabNum};
                    delete(app.CurrTab);
                    app.CurrTab=app.Tabs{NewTabNum};
                else
                    app.Tabs(OldTabIdx)=[];
                    delete(app.CurrTab);
                    app.TabGroup.SelectedTab = app.Tabs{1};
                    app.CurrTab=app.Tabs{1};
                end

            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.CurrTab = app.TabGroup.SelectedTab;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DataMenu = uimenu(app.UIFigure);
            app.DataMenu.Text = 'Data';
            app.ShowdatanodeMenu = uimenu(app.DataMenu);
            app.ShowdatanodeMenu.Text = 'Show data node';
            app.SaveplotsMenu = uimenu(app.DataMenu);
            app.SaveplotsMenu.Text = 'Save plots';
            app.ExportplotMenu = uimenu(app.DataMenu);
            app.ExportplotMenu.Text = 'Export plot';
            app.ExportallplotsMenu = uimenu(app.DataMenu);
            app.ExportallplotsMenu.Text = 'Export all plots';
            app.PlotsMenu = uimenu(app.UIFigure);
            app.PlotsMenu.Text = 'Plots';
            app.AddnewplotMenu = uimenu(app.PlotsMenu);
            app.AddnewplotMenu.Text = 'Add new plot';
            app.xyMenu = uimenu(app.AddnewplotMenu);
            app.xyMenu.Text = 'x, y';
            app.xyy2Menu = uimenu(app.AddnewplotMenu);
            app.xyy2Menu.Text = 'x, y, y2';
            app.xyx2y2Menu = uimenu(app.AddnewplotMenu);
            app.xyx2y2Menu.Text = 'x,y, x2, y2';
            app.TensileShearMenu = uimenu(app.AddnewplotMenu);
            app.TensileShearMenu.Text = 'Tensile / Shear';
            app.DeletecurrentplotMenu = uimenu(app.PlotsMenu);
            app.DeletecurrentplotMenu.Text = 'Delete current plot';
            app.LegendMenu = uimenu(app.UIFigure);
            app.LegendMenu.Text = 'Legend';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.GridLayout2 = uigridlayout(app.Tab);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DropDown = uidropdown(app.GridLayout);
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = 'Panel';
            app.GridLayout3 = uigridlayout(app.Panel);
            app.Label = uilabel(app.GridLayout3);
            app.EditFieldLabel = uilabel(app.GridLayout3);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.GridLayout3, 'text');
            app.EditField_2Label = uilabel(app.GridLayout3);
            app.EditField_2Label.Text = 'Edit Field';
            app.EditField_2 = uieditfield(app.GridLayout3, 'text');
            app.EditField_3Label = uilabel(app.GridLayout3);
            app.EditField_3Label.Text = 'Edit Field';
            app.EditField_3 = uieditfield(app.GridLayout3, 'text');
            app.ButtonGroup = uibuttongroup(app.GridLayout3);
            app.ButtonGroup.Title = 'Button Group';
            app.Button = uitogglebutton(app.ButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uitogglebutton(app.ButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uitogglebutton(app.ButtonGroup);
            app.Button3.Text = 'Button3';
            app.NewButton = uibutton(app.GridLayout, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New';
            app.CloseButton = uibutton(app.GridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectPlotter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end